classdef CalculosApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CalcularButton          matlab.ui.control.Button
        HipertensionEditField4  matlab.ui.control.NumericEditField
        conhipertencionLabel_2  matlab.ui.control.Label
        conhipertensionLabel    matlab.ui.control.Label
        DiabetesEditField3      matlab.ui.control.EditField
        CondiabetesLabel        matlab.ui.control.Label
        condiavetesLabel        matlab.ui.control.Label
        EdadPromedioEditField2  matlab.ui.control.NumericEditField
        PromedioLabel           matlab.ui.control.Label
        EdadpromedioLabel       matlab.ui.control.Label
        TotalEditField          matlab.ui.control.NumericEditField
        TotalLabel              matlab.ui.control.Label
        TotaldepersonasLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            conn = conectarPostgres();
    data = fetch(conn, 'SELECT * FROM personas');
    close(conn);

    % Calculos
    total = height(data);
    edadProm = mean(data.edad);
    porcentajeDiabetes = 100 * sum(data.tiene_diabetes) / total;
    porcentajeHipertension = 100 * sum(data.tiene_hipertension) / total;

    % Mostrar
    app.NumPersonasEditField.Value = total;
    app.EdadPromedioEditField.Value = round(edadProm, 2);
    app.DiabetesEditField.Value = sprintf('%.1f%%', porcentajeDiabetes);
    app.HipertensionEditField.Value = sprintf('%.1f%%', porcentajeHipertension);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TotaldepersonasLabel = uilabel(app.UIFigure);
            app.TotaldepersonasLabel.Text = 'Total de personas:';
            app.TotalLabel = uilabel(app.UIFigure);
            app.TotalLabel.Text = 'Total ';
            app.TotalEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalEditField.Editable = 'off';
            app.TotalEditField.Value = 50;
            app.EdadpromedioLabel = uilabel(app.UIFigure);
            app.EdadpromedioLabel.Text = 'Edad promedio:';
            app.PromedioLabel = uilabel(app.UIFigure);
            app.PromedioLabel.Text = 'Promedio';
            app.EdadPromedioEditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EdadPromedioEditField2.Editable = 'off';
            app.EdadPromedioEditField2.Value = 30;
            app.condiavetesLabel = uilabel(app.UIFigure);
            app.condiavetesLabel.Text = '% con diavetes:';
            app.CondiabetesLabel = uilabel(app.UIFigure);
            app.CondiabetesLabel.Text = 'Con diabetes';
            app.DiabetesEditField3 = uieditfield(app.UIFigure, 'text');
            app.DiabetesEditField3.Editable = 'off';
            app.DiabetesEditField3.Value = '0.05';
            app.conhipertensionLabel = uilabel(app.UIFigure);
            app.conhipertensionLabel.Text = '% con hipertension';
            app.conhipertencionLabel_2 = uilabel(app.UIFigure);
            app.conhipertencionLabel_2.Text = 'con hipertencion';
            app.HipertensionEditField4 = uieditfield(app.UIFigure, 'numeric');
            app.HipertensionEditField4.Editable = 'off';
            app.HipertensionEditField4.Value = 0.01;
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalculosApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end