classdef DatosApp_LISTO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        VovelButton      matlab.ui.control.Button
        RefrescarButton  matlab.ui.control.Button
        ExportarButton   matlab.ui.control.Button
        buscarButton     matlab.ui.control.Button
        EliminarButton   matlab.ui.control.Button
        AgregarButton    matlab.ui.control.Button
        CargarButton     matlab.ui.control.Button
        DatosTable       matlab.ui.control.Table
    end

    
    properties (Access = public)
        BuscarEditField  matlab.ui.control.EditField
    end
    
    properties (Access = private)
        conn % Description
    end
    
    methods (Access = public)
        
        % Función para actualizar la tabla (añade esto)
    function actualizarTabla(app)
        try
            data = fetch(app.conn, 'SELECT * FROM personas');
            app.DatosTable.Data = data;
        catch ME
            uialert(app.UIFigure, 'Error al actualizar datos', 'Error', 'Icon', 'error');
            disp(ME.message);
        end
    end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            app.conn = conectarPostgres();
    
    % Consulta SQL
    data = fetch(app.conn, 'SELECT * FROM personas');
    
    % Mostrar en la tabla
    app.DatosTable.Data = data;

    % Cerrar conexión
    close(app.conn);

        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            prompt = {'Código:', 'Nombre:', 'Categoría:', 'Precio:', 'Cantidad:', 'Proveedor:'};
    title = 'Nuevo articulo';
    dims = [1 35];
    default = {'', '', '', '0', '0', ''};
    answer = inputdlg(prompt, title, dims, default);

    if ~isempty(answer)
        try
            app.conn = app.conn;
            insertquery = sprintf("INSERT INTO articulos (codigo, nombre, categoria, precio, cantidad, proveedor) VALUES ('%s', '%s', '%s', %s, %s, '%s')", ...
               answer{1}, answer{2}, answer{3}, answer{4}, answer{5}, answer{6});
            exec(app.conn, insertquery);
            uialert(app.UIFigure, 'articulo agregado correctamente.', 'Éxito');
            app.cargarInventario(); % Refrescar tabla
        catch ME
            uialert(app.UIFigure, 'Error al agregar el articulo.', 'Error', 'Icon', 'error');
            disp(ME.message);
        end
    end
    
        end

        % Button pushed function: buscarButton
        function buscarButtonPushed(app, event)
            filtro = app.BuscarEditField.Value;
            query = sprintf("SELECT * FROM productos WHERE LOWER(nombre) LIKE '%%%s%%'", lower(filtro));
            data = fetch(app.conn, query);
            app.UITable.Data = data;
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            idx = app.UITable.Selection;
if isempty(idx)
    uialert(app.UIFigure, 'Seleccione un articulo para eliminar.', 'Aviso');
    return;
end

% Confirmar
selection = uiconfirm(app.UIFigure, ...
    '¿Está seguro de que desea eliminar este articulo?', ...
    'Confirmar eliminación', ...
    'Options', {'Sí','No'}, 'DefaultOption', 2);

if strcmp(selection, 'Sí')
    productoID = app.UITable.Data{idx(1), 'ID'}; % Asumiendo que tienes una columna ID

    % Ejecutar eliminación en la base de datos
    app.conn = app.conn; % conexión activa a PostgreSQL
    sql = sprintf("DELETE FROM articulos WHERE id = %d", productoID);
    exec(app.conn, sql);

    % Actualizar tabla
    actualizarTabla(app);
end
        end

        % Button pushed function: RefrescarButton
        function RefrescarButtonPushed(app, event)
            app.conn = app.conn; % conexión PostgreSQL activa
            datos = fetch(app.conn, "SELECT * FROM articulos ORDER BY id");
            app.UITable.Data = datos;
        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            app.conn = app.conn; % conexión PostgreSQL activa
            datos = fetch(app.conn, "SELECT * FROM articulos ORDER BY id");
            app.UITable.Data = datos;
        end

        % Button pushed function: VovelButton
        function VovelButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure, ...
        '¿Desea volver al Menú?', ...  % Mensaje
        'Confirmar salida', ...                                 % Título
        'Options', {'Sí', 'No'}, ...                            % Botones
        'DefaultOption', 2, ...                                 % Opción por defecto: No
        'CancelOption', 2);  
         if strcmp(selection, 'Sí')
            MainMenuApp;
         end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatosTable = uitable(app.UIFigure);
            app.DatosTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DatosTable.RowName = {};
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Gestion de Inventario';
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.buscarButton = uibutton(app.UIFigure, 'push');
            app.buscarButton.ButtonPushedFcn = createCallbackFcn(app, @buscarButtonPushed, true);
            app.buscarButton.Text = 'buscar';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            app.RefrescarButton = uibutton(app.UIFigure, 'push');
            app.RefrescarButton.ButtonPushedFcn = createCallbackFcn(app, @RefrescarButtonPushed, true);
            app.RefrescarButton.Text = 'Refrescar';
            app.VovelButton = uibutton(app.UIFigure, 'push');
            app.VovelButton.ButtonPushedFcn = createCallbackFcn(app, @VovelButtonPushed, true);
            app.VovelButton.Text = 'Vovel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatosApp_LISTO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end